classdef IP_Gui_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ImageAxes              matlab.ui.control.UIAxes
        PredefinedImagesLabel  matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        LoadButton             matlab.ui.control.Button
        RunFiltersButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        im % Description
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                app.im = imread('corn.tif', 2);
            else
                try
                    app.im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(app.im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,app.im);
                    
                    % Plot all histograms with the same data for grayscale
                   
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,app.im);
                    
                    % Plot the histograms
                    
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
              
                
                % Set y axes limits based on largest bin count


        end
        
        
        function updateFilters(app)
        
        img = app.im;
       
        img_gray = rgb2gray(img);


        img_gauss = imgaussfilt(img_gray, 8);


        can = edge(img_gauss, "Canny", .1);
        app.im = img_gauss;
        
        
        Y = size(img, 1);
        X = size(img, 2);
        my_vertices = [X/2 Y/2; 0 Y; X Y];
        
        h = drawpolygon('Position', my_vertices);


        %draw mask for certain area we need - test
        figure(2);
        x = [X/2 0 X];
        y = [Y/2 Y Y];
        bw = poly2mask(x, y, Y, X);


        app.im = bw;


        masked = uint8(Apply_Filter(app,can, bw));
        
        [HT, theta, rho] = hough(masked);
        
        P  = houghpeaks(HT, 5, "threshold", 100);%ceil(0.3 * max(HT(:))));
        lines = houghlines(masked, theta, rho, P, "FillGap", 25, "MinLength", 10);
       
        figure, imshow(img), hold on
        max_len = 0;
        for k = 1:length(lines)
           xy = [lines(k).point1; lines(k).point2];
           plot(xy(:, 1), xy(:, 2), "LineWidth", 2, "Color", "green");
        
           % Plot beginnings and ends of lines
           plot(xy(1, 1), xy(1, 2), "x", "LineWidth", 2, "Color", "yellow");
           plot(xy(2, 1), xy(2, 2), "x", "LineWidth", 2, "Color", "red");
        
           % Determine the endpoints of the longest line segment
           len = norm(lines(k).point1 - lines(k).point2);
           if (len > max_len)
              max_len = len;
              xy_long = xy;
           end
        end
       
        imagesc(app.ImageAxes,app.im);
        
        end
    end
    
    methods (Access = public)
        
        function output = Apply_Filter(app,img, filter)
            img = im2uint8(img);
        u_filter = uint8(filter);
        filtered_img = zeros(size(img));
        
        for i = 1:size(img, 1)
            for q = 1:size(img, 2)
                filtered_img(i, q) = img(i, q) * u_filter(i, q);
            end
        end
        
    output = filtered_img;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'peppers.png');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Button pushed function: RunFiltersButton
        function filterButtonPress(app, event)
        
        updateFilters(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Peppers', 'Corn'};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'peppers.png';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.RunFiltersButton = uibutton(app.UIFigure, 'push');
            app.RunFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPress, true);
            app.RunFiltersButton.Text = 'Run Filters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IP_Gui_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end